classdef math < matlab.apps.AppBase
    %MATH 方法

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        resetButton                    matlab.ui.control.Button
        extralengthEditField           matlab.ui.control.NumericEditField
        extralengthEditFieldLabel      matlab.ui.control.Label
        extraneedlesEditField          matlab.ui.control.NumericEditField
        extraneedlesEditFieldLabel     matlab.ui.control.Label
        colourDropDown                 matlab.ui.control.DropDown
        colourDropDownLabel            matlab.ui.control.Label
        similiarneedlesSpinner         matlab.ui.control.Spinner
        similiarneedlesSpinnerLabel    matlab.ui.control.Label
        predictedresultLabel           matlab.ui.control.Label
        presstorunButton               matlab.ui.control.Button
        widthEditField                 matlab.ui.control.NumericEditField
        widthEditFieldLabel            matlab.ui.control.Label
        planksEditField                matlab.ui.control.NumericEditField
        planksEditFieldLabel           matlab.ui.control.Label
        squaresEditField               matlab.ui.control.NumericEditField
        squaresEditFieldLabel          matlab.ui.control.Label
        lengthofneedlesEditField       matlab.ui.control.NumericEditField
        lengthofneedlesEditFieldLabel  matlab.ui.control.Label
        numberofneedlesEditField       matlab.ui.control.NumericEditField
        numberofneedlesEditFieldLabel  matlab.ui.control.Label
        selecttaskDropDown             matlab.ui.control.DropDown
        selecttaskDropDownLabel        matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        needleLines  
        needleAngles
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: presstorunButton
        function presstorunButtonPushed(app, event)
                task = app.selecttaskDropDown.Value;
                width = app.widthEditField.Value; 
                planks = app.planksEditField.Value; 
                length = app.lengthofneedlesEditField.Value;
                throws = app.numberofneedlesEditField.Value;
                numberOfSquares = app.squaresEditField.Value;
                squareSideLength = length;
                color = app.colourDropDown.Value;
                extralength = app.extralengthEditField.Value;
                extranumber = app.extraneedlesEditField.Value;
                app.UIAxes.XAxis.Visible = 'on';
                cla(app.UIAxes);
                if length > width
                    uialert(app.UIFigure, 'Needle length must be less than the width (spacing) between the planks.', 'Error', 'Icon', 'error');
                    return;
                end
                 if extralength > width
                    uialert(app.UIFigure, 'Extra Needle length must be less than the width (spacing) between the planks.', 'Error', 'Icon', 'error');
                    return;
                end

                ax = app.UIAxes;
                cla(ax);   
                xlim(ax, [0, width * (planks +1)]);
                ylim(ax, [0, 100])
                ax.XTick = 0:width:(width *(planks + 1));
                for i = 1:planks 
                     xPosition = i * width; 
                     line(ax, [xPosition, xPosition], [0, app.UIAxes.Position(4)], 'Color', 'blue'); 
                end


                cracks = width * (1:planks+1);
                switch task
                    case 'task 1'
                            
                            [p, ~] = BuffonStandardPi(width, length, throws); 
                            app.needleLines = gobjects(1, throws);
                            app.needleAngles = zeros(1, throws);

                            for n = 1:throws
                                xPos = rand * width* planks;
                                yPos = rand * 100;
                                angle = rand * 2 * pi;
                                xEnd = xPos + length * cos(angle);
                                yEnd = yPos + length * sin(angle);
                                intersects = any((xPos < cracks & xEnd > cracks) | (xEnd < cracks & xPos > cracks));
                                if intersects
                                    color = 'magenta';
                                else
                                    color = app.colourDropDown.Value;
                                end

                                app.needleLines(n) = line(ax, [xPos, xEnd], [yPos, yEnd], 'Color', color, 'ButtonDownFcn', @(src, event)needleClickCallback(app, src, event));
                                app.needleAngles(n) = angle;
                            end
                           
                           for e = 1:extranumber
                                x2 = rand * width * planks;
                                y2 = rand * 100;
                                angle = rand * 2 * pi;
                                xd = x2 + extralength * cos(angle);
                                yd = y2 + extralength * sin(angle);
                                line(ax, [x2, xd], [y2, yd], 'Color', 'black');
                                
                           end


                            app.predictedresultLabel.Text = sprintf('Predicted Pi for Needles: %s', num2str(p));
                         
        
                    case 'task 2'
                             if app.squaresEditField.Value == 0
                                uialert(app.UIFigure, 'Number of squares cannot be zero.', 'Input Error', 'Icon', 'error');
                                return;
                             end
                            
                            [p, ~] = BuffonSquaresPi(width, squareSideLength, planks);

                             for s = 1:numberOfSquares
                                    squareX = rand * (width * planks - squareSideLength);
                                    squareY = rand * (100 - squareSideLength);
                                    angle = rand * 2 * pi;
                                    cornersX = squareX + [0, squareSideLength, squareSideLength, 0, 0];
                                    cornersY = squareY + [0, 0, squareSideLength, squareSideLength, 0];
                                    centerX = squareX + squareSideLength / 2;
                                    centerY = squareY + squareSideLength / 2;
                                    rotatedCornersX = cos(angle) * (cornersX - centerX) - sin(angle) * (cornersY - centerY) + centerX;
                                    rotatedCornersY = sin(angle) * (cornersX - centerX) + cos(angle) * (cornersY - centerY) + centerY;
                                    in = any(arrayfun(@(i) any(rotatedCornersX(i) < cracks & rotatedCornersX(i+1) > cracks | rotatedCornersX(i+1) < cracks & rotatedCornersX(i) > cracks), 1:4));
                                    
                                    if in
                                        color = 'magenta';
                                    else
                                        color = app.colourDropDown.Value;
                                    end
                            for i = 1:4
                                    line(ax, [rotatedCornersX(i), rotatedCornersX(i+1)], [rotatedCornersY(i), rotatedCornersY(i+1)], 'Color', color);
                            end
                            end
                            app.predictedresultLabel.Text = sprintf('Predicted Pi: %s', num2str(p));
        
                            
                    case 'task 3'
                            [r, ~] = BuffonSquaresRootTwo(width, squareSideLength, numberOfSquares);
                        
                            if app.squaresEditField.Value == 0
                                uialert(app.UIFigure, 'Number of squares cannot be zero.', 'Input Error', 'Icon', 'error');
                                return;
                             end
                            for s = 1:numberOfSquares
                                squareX = rand * (width * planks - squareSideLength);
                                squareY = rand * (100 - squareSideLength);
                                angle = rand * 2 * pi;
                                cornersX = squareX + [0, squareSideLength, squareSideLength, 0, 0];
                                cornersY = squareY + [0, 0, squareSideLength, squareSideLength, 0];
                                centerX = squareX + squareSideLength / 2;
                                centerY = squareY + squareSideLength / 2;
                                rotatedCornersX = cos(angle) * (cornersX - centerX) - sin(angle) * (cornersY - centerY) + centerX;
                                rotatedCornersY = sin(angle) * (cornersX - centerX) + cos(angle) * (cornersY - centerY) + centerY;
                                 in = any(arrayfun(@(i) any(rotatedCornersX(i) < cracks & rotatedCornersX(i+1) > cracks | rotatedCornersX(i+1) < cracks & rotatedCornersX(i) > cracks), 1:4));                               
                                    if in
                                        color = 'magenta';
                                    else
                                        color = app.colourDropDown.Value;
                                    end
                                for i = 1:4
                                    line(ax, [rotatedCornersX(i), rotatedCornersX(i+1)], [rotatedCornersY(i), rotatedCornersY(i+1)], 'Color', color);
                                end
                        app.predictedresultLabel.Text = sprintf('Estimated sqrt(2): %.5f', r);
                            end

                end


         function [p, crossings] = BuffonStandardPi(width, length, throws)
                    crossings = 0;
                    for n = 1:throws
                        d = rand(1, 1) * width / 2;
                        angle = rand(1, 1) * pi;
                        if d <= (length / 2) * sin(angle)
                             crossings = crossings + 1;
                        end
                        
                        
                    end
                    if crossings > 0
                        p = 2 * length * throws / (width * crossings);
                    else
                        p = 'check your input';
                    end

        end
        function [p, crossings] = BuffonSquaresPi(width, length, throws)
                        crossings = 0;
                            for i = 1:throws
                                for j = 1:4  
                                    x = rand(1,1)*width/2;
                                    theta = rand(1,1)*pi/2;
                                    if x <= length/2*sin(theta)
                                        crossings = crossings + 1;
                                    end
                                end
                            end
                            
                    if crossings > 0
                        p = 2 * length * throws * 4 / (width * crossings); 
                    else
                        p = 'check your input';
                    end
        end

        function [r, crossings] = BuffonSquaresRootTwo(width, length, throws)
            
                crossings = 0;
                for s = 1:throws   
                            squareX = rand * (width * planks - squareSideLength);
                            squareY = rand * (100 - squareSideLength);
                            angle = rand * 2 * pi;
                            cornersX = squareX + [0, squareSideLength, squareSideLength, 0, 0];
                            cornersY = squareY + [0, 0, squareSideLength, squareSideLength, 0];
                            centerX = squareX + squareSideLength / 2;
                            centerY = squareY + squareSideLength / 2;
                            rotatedCornersX = cos(angle) * (cornersX - centerX) - sin(angle) * (cornersY - centerY) + centerX;
                            rotatedCornersY = sin(angle) * (cornersX - centerX) + cos(angle) * (cornersY - centerY) + centerY;
                            for i = 1:4
                                 xMin = min(rotatedCornersX(i), rotatedCornersX(i+1));
                                 xMax = max(rotatedCornersX(i), rotatedCornersX(i+1));
                                  for j = 1:planks
                                    plankPosition = j * width;
                                    if xMin < plankPosition && plankPosition < xMax
                                        crossings = crossings + 1;
                                        break;
                                    end
                                  end
                            end             
                    end
                    
                p = 2 * length * throws * 4 / (width * crossings); 
                D = sqrt(2) * length;      
                pa = 4 * length / (pi * width);
                pb = 4 * (2 * length - D) / (p * width);
                pn = pb / pa;
                r = 2 - pn;
        end


            function needleClickCallback(app, src,event)
                cracks = width * (1:planks+1);
                color = app.colourDropDown.Value;
                for idx = 1:numel(app.needleLines)
                    lineObj = app.needleLines(idx);
                    xPos = lineObj.XData(1);
                    xEnd = lineObj.XData(2);
                    intersects = any((xPos < cracks & xEnd > cracks) | (xEnd < cracks & xPos > cracks));
                    if intersects
                        set(lineObj, 'Color', 'magenta'); 
                    else
                        set(lineObj, 'Color', color);
                    end
                end
                
                clickedNeedleIndex = find(app.needleLines == src);
                highlightSimilarNeedles(app, clickedNeedleIndex);
                 if ~isempty(clickedNeedleIndex)
                    needleLength = app.lengthofneedlesEditField.Value;
                    needleAngle = atan2(src.YData(2) - src.YData(1), src.XData(2) - src.XData(1));
                    needleAngle = rad2deg(needleAngle); 
                    if needleAngle < 0
                        needleAngle = needleAngle + 360;
                    end
                    delete(findall(app.UIFigure, 'Type', 'annotation')); 
                    annotation(app.UIFigure, 'textbox', [0.3, 0.7, 0.1, 0.1], 'String', sprintf('Length: %.2f, Angle: %.2f°', needleLength, needleAngle), 'FitBoxToText', 'on', 'BackgroundColor', 'white');
                end
                
            end


        function highlightSimilarNeedles(app, needleIndex)
                q = app.similiarneedlesSpinner.Value; 
                targetAngle = app.needleAngles(needleIndex);
                angleDifferences = abs(app.needleAngles - targetAngle); 
                [~, sortedIndices] = sort(angleDifferences); 
                similarneedles = sortedIndices(1:q+1); 
                set(app.needleLines(similarneedles), 'Color', 'green');
        end

            
        end

        % Value changed function: selecttaskDropDown
        function selecttaskDropDownValueChanged(app, event)
            delete(findall(app.UIFigure, 'Type', 'annotation'));
            switch app.selecttaskDropDown.Value
                case 'task 1'
                    app.squaresEditField.Enable = 'off';
                    app.numberofneedlesEditField.Enable = 'on';
                    app.extralengthEditField.Enable = "on";
                    app.extraneedlesEditField.Enable = "on";
                case 'task 2'
                    app.numberofneedlesEditField.Enable = 'off';
                    app.squaresEditField.Enable = 'on'; 
                    app.extralengthEditField.Enable = "off";
                    app.extraneedlesEditField.Enable = "off";
                case 'task 3'
                    app.numberofneedlesEditField.Enable = 'off';
                    app.squaresEditField.Enable = 'on';
                    app.extralengthEditField.Enable = "off";
                    app.extraneedlesEditField.Enable = "off";
            end
        end

        % Button pushed function: resetButton
        function resetButtonPushed(app, event)
            cla(app.UIAxes);
            app.numberofneedlesEditField.Value = 100; 
            app.lengthofneedlesEditField.Value = 5; 
            app.squaresEditField.Value = 0;
            app.squaresEditField.Enable = 'off'; 
            app.widthEditField.Value = 10; 
            app.planksEditField.Value = 10; 
            app.colourDropDown.Value = 'red'; 
            app.needleLines = gobjects(0); 
            app.needleAngles = []; 
            app.predictedresultLabel.Text = 'Predicted result:'; 
            delete(findall(app.UIFigure, 'Type', 'annotation'));
            ax = app.UIAxes;
            xlim(ax, [0, app.widthEditField.Value * (app.planksEditField.Value + 1)]);
            ylim(ax, [0, 100]);
            ax.XTick = 0:app.widthEditField.Value:(app.widthEditField.Value * (app.planksEditField.Value + 1));
            selecttaskDropDownValueChanged(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.selecttaskDropDownLabel = uilabel(app.UIFigure);
            app.selecttaskDropDownLabel.Text = 'select task';
            app.selecttaskDropDown = uidropdown(app.UIFigure);
            app.selecttaskDropDown.Items = {'task 1', 'task 2', 'task 3'};
            app.selecttaskDropDown.ValueChangedFcn = createCallbackFcn(app, @selecttaskDropDownValueChanged, true);
            app.selecttaskDropDown.Value = 'task 1';
            app.numberofneedlesEditFieldLabel = uilabel(app.UIFigure);
            app.numberofneedlesEditFieldLabel.Text = 'number of needles';
            app.numberofneedlesEditField = uieditfield(app.UIFigure, 'numeric');
            app.numberofneedlesEditField.Limits = [0 10000000];
            app.numberofneedlesEditField.Value = 100;
            app.lengthofneedlesEditFieldLabel = uilabel(app.UIFigure);
            app.lengthofneedlesEditFieldLabel.Text = 'length of needles';
            app.lengthofneedlesEditField = uieditfield(app.UIFigure, 'numeric');
            app.lengthofneedlesEditField.Value = 5;
            app.squaresEditFieldLabel = uilabel(app.UIFigure);
            app.squaresEditFieldLabel.Text = 'squares';
            app.squaresEditField = uieditfield(app.UIFigure, 'numeric');
            app.squaresEditField.Enable = 'off';
            app.planksEditFieldLabel = uilabel(app.UIFigure);
            app.planksEditFieldLabel.Text = 'planks';
            app.planksEditField = uieditfield(app.UIFigure, 'numeric');
            app.planksEditField.Value = 10;
            app.widthEditFieldLabel = uilabel(app.UIFigure);
            app.widthEditFieldLabel.Text = 'width';
            app.widthEditField = uieditfield(app.UIFigure, 'numeric');
            app.widthEditField.Value = 10;
            app.presstorunButton = uibutton(app.UIFigure, 'push');
            app.presstorunButton.ButtonPushedFcn = createCallbackFcn(app, @presstorunButtonPushed, true);
            app.presstorunButton.Text = 'press to run';
            app.predictedresultLabel = uilabel(app.UIFigure);
            app.predictedresultLabel.Text = 'predicted result:';
            app.similiarneedlesSpinnerLabel = uilabel(app.UIFigure);
            app.similiarneedlesSpinnerLabel.Text = 'similiar needles';
            app.similiarneedlesSpinner = uispinner(app.UIFigure);
            app.similiarneedlesSpinner.Value = 3;
            app.colourDropDownLabel = uilabel(app.UIFigure);
            app.colourDropDownLabel.Text = 'colour';
            app.colourDropDown = uidropdown(app.UIFigure);
            app.colourDropDown.Items = {'red', 'blue', 'yellow', 'black', 'cyan'};
            app.colourDropDown.Value = 'red';
            app.extraneedlesEditFieldLabel = uilabel(app.UIFigure);
            app.extraneedlesEditFieldLabel.Text = 'extra needles';
            app.extraneedlesEditField = uieditfield(app.UIFigure, 'numeric');
            app.extralengthEditFieldLabel = uilabel(app.UIFigure);
            app.extralengthEditFieldLabel.Text = 'extra length';
            app.extralengthEditField = uieditfield(app.UIFigure, 'numeric');
            app.resetButton = uibutton(app.UIFigure, 'push');
            app.resetButton.ButtonPushedFcn = createCallbackFcn(app, @resetButtonPushed, true);
            app.resetButton.Text = 'reset';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = math

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end